classdef kaoLightTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LaserSweepButton      matlab.ui.control.Button
        StartTestButton       matlab.ui.control.Button
        RepeatsSpinnerLabel   matlab.ui.control.Label
        RepeatsSpinner        matlab.ui.control.Spinner
        TestRunningLampLabel  matlab.ui.control.Label
        TestRunningLamp       matlab.ui.control.Lamp
        StopTestButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        SaveAngleButton       matlab.ui.control.Button
        DeleteAngleButton     matlab.ui.control.Button
        PanSliderLabel        matlab.ui.control.Label
        PanSlider             matlab.ui.control.Slider
        TiltSliderLabel       matlab.ui.control.Label
        TiltSlider            matlab.ui.control.Slider
        TestNumberGaugeLabel  matlab.ui.control.Label
        TestNumberGauge       matlab.ui.control.Gauge
        LaserSwitchLabel      matlab.ui.control.Label
        LaserSwitch           matlab.ui.control.ToggleSwitch
        ServoAngleGrid        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        digital_output = 0 %stores the current digital output value
        stop_test = false; %indicates if the test should be stopped
        laser_output = 4096; % Digital output of the laser toggle
        laser_state = 8192; % Digital output of the laser state
        test_digital_outputs = []; % digital outputs used in the experiment
        test_tilt_slider_val = []; % Tilt slider value for saved angles
        test_pan_slider_val = []; % Pan slider value for saved angles
        current_angle_selected = -1; % Current angle in the table selected
    end
    
    methods (Access = private)
        function [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(~, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps)
            now = xippmex('time'); %get the current sample time
            [foot_data, foot_time_stamp] = xippmex('cont', 10241, (now - start_time) / 30, '1ksps', start_time); %record data on the first SMA analog input between the start and current time
            [laser_data, laser_time_stamp] = xippmex('cont', 10242, (now - start_time) / 30, '1ksps', start_time); %record data on the second SMA analog input between the start and current time
            start_time = now; %move the start time to when the last recording window ended

            foot_tot_data = [foot_tot_data, foot_data]; %add the recorded foot pedal data to the end of the rest of the data
            foot_stamps = [foot_stamps, double(foot_time_stamp) / 30 : (double(foot_time_stamp) / 30 + length(foot_data) - 1)]; %saves the times of the foot pedal samples
            laser_tot_data = [laser_tot_data, laser_data]; %add the recorded laser state data to the end of the rest of the data
            laser_stamps = [laser_stamps, double(laser_time_stamp) / 30 : (double(laser_time_stamp) / 30 + length(laser_data) - 1)]; %saves the times of the samples
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LaserToggleButtonValueChanged(app, event)
            value = app.LaserToggleButton.Value;
            if value == 1
                xippmex('digout', 5, app.digital_output + app.laser_output); %if the toggle button is on, turn on the laser with whatever the current servo postions are
            else
                xippmex('digout', 5, app.digital_output); %if the toggle button is off, turn off the laser with whatever the current servo postions are
            end
        end

        % Button pushed function: LaserSweepButton
        function LaserSweepButtonPushed(app, event)
            if size(app.test_digital_outputs) == 0
                error('You have not added any test positions yet.')
            else %sweep the laser in the horizontal direction along the current tilt angle
               for i = 1 : length(app.test_digital_outputs)
                   app.digital_output = app.test_digital_outputs(i);
                   xippmex('digout', 5, app.digital_output + app.laser_output);
                   pause(0.5)
               end
            end
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            if size(app.test_digital_outputs) == 0
                error("You havn't selected any angles to use");
            else
                app.stop_test = 0;
                app.TestRunningLamp.Color = 'green';
                
                target_num = app.RepeatsSpinner.Value; %the number of repeated target points
                targets = repelem(app.test_digital_outputs, target_num); %the test set unrandomized
                trial_list_rows = repelem(1 : length(app.test_digital_outputs), target_num); % the trial positions repeated with their targets, but not randomized
                target_randomization = randperm(length(targets)); % logical array to rearrage targets and test list indices
                targets = targets(target_randomization); %the targets array randomized
                trial_list_rows = trial_list_rows(target_randomization); % matches trial row to the target randomization
                
                %run through the target array and record the results
                test_delay = 1; %the minimum delay between tests
                random_delay = 3; %the random amount of time the delay could increase by
                foot_tot_data = []; %empty array to store the recorded foot pedal data
                foot_stamps = []; %empty array to store the clock times of the foot pedal data
                laser_tot_data = []; %empty array to store the recorded laser activation data
                laser_stamps = []; %empty array to store the clock times of the foot pedal data
                start_time = xippmex('time');%start point of test
                app.TestNumberGauge.Limits = [1, length(targets)];
                for i = 1 : length(targets)
                    if app.stop_test
                        break;
                    end
                    disp(['Test ',num2str(i),' of ', num2str(length(targets))])%display the current test and total test number
                    app.TestNumberGauge.Value = i;
                    delay = test_delay + rand(1,1) * random_delay; %the delay amount

                    target = targets(i);
                    xippmex('digout', 5, target); %move the servos to the test positions before the delay

                    %record the foot pedal and laser state delay and record
                    %again
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                    pause(delay);
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                    xippmex('digout', 5, app.digital_output + app.laser_output + app.laser_state); %turn on the laser and laser state indicator

                    pause(3); %give the subject 3 seconds to react
                    
                    xippmex('digout', 5, app.digital_output); %turn off the laser
                    
                    %record the foot pedal and laser state
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                end
                
                disp('DONE');
                app.TestRunningLamp.Color = 'red';
                
                %create a time stamp for the data set
                DT = clock;
                year = num2str(DT(1));
                if DT(2)<10
                    month = strcat('0',num2str(DT(2)));
                else
                    month = num2str(DT(2));
                end
                if DT(3)<10
                    day = strcat('0',num2str(DT(3)));
                else
                    day = num2str(DT(3));
                end

                foot_pedal_data_name = strcat('Foot_Pedal_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% foot pedal data file name
                foot_pedal_wave = [foot_stamps; foot_tot_data];%combine the timing and voltage data from the foot pedal
                save(foot_pedal_data_name, 'foot_pedal_wave');%save foot pedal data

                laser_data_name = strcat('Laser_State_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% laser state data file name
                laser_state_wave = [laser_stamps; laser_tot_data];%combine the timing and voltage data from the digital putput for the laser state
                save(laser_data_name, 'laser_state_wave');%save laser state data
                
                digital_output_name = strcat('Digital_Output_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% digital output data file name
                save(digital_output_name, 'targets');
                
                trial_list_row_log_name = strcat('Trial_Number_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% trial table row data file name
                save(trial_list_row_log_name, "trial_list_rows");
            end
        end

        % Button pushed function: StopTestButton
        function StopTestButtonPushed(app, event)
            app.stop_test = true;
        end

        % Button pushed function: SaveAngleButton
        function SaveAngleButtonPushed(app, event)
            app.test_pan_slider_val = cat(1, app.test_pan_slider_val, app.PanSlider.Value + 27);
            app.test_tilt_slider_val = cat(1, app.test_tilt_slider_val, app.TiltSlider.Value + 74);
            app.test_digital_outputs = cat(1, app.test_digital_outputs, app.digital_output);
            
            fhv = app.UIFigure; % Current app status
            app.UIFigure.HandleVisibility = 'callback'; % give access to callback            
            set(0, 'CurrentFigure', app.UIFigure)
            plot(app.test_pan_slider_val - 27, app.test_tilt_slider_val - 74, 'o', "MarkerFaceColor", "red");
            app.UIFigure.HandleVisibility = fhv.HandleVisibility; % return original state
            
            app.UITable.Data = [app.test_pan_slider_val, app.test_tilt_slider_val, app.test_digital_outputs]; %update the table of servo angles
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.current_angle_selected = indices(1, 1);
            if app.LaserSwitch.Value == "On"
                xippmex('digout', 5, app.test_digital_outputs(indices(1, 1)) + app.laser_state); % go to the selected angle with laser on
            else
                xippmex('digout', 5, app.test_digital_outputs(indices(1, 1))); % go to the selected angle
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            new_data = event.NewData;
            if new_data < 1
                error('Value must be above 0.')
            end
            if indices(1, 2) == 1
                app.test_pan_slider_val(indices(1, 1)) = new_data;
                app.test_digital_outputs(indices(1, 1)) = (new_data - 27) + bitshift(app.test_tilt_slider_val(indices(1, 1)), 7);
            elseif indices(1, 2) == 2
                app.test_tilt_slider_val(indices(1, 1)) = new_data;
                app.test_digital_outputs(indices(1, 1)) = app.test_pan_slider_val(indices(1, 1)) + bitshift((new_data - 74), 7);
            else
                error("You can't edit this column");
            end
            app.UITable.Data(indices(1, 1), 3) = app.test_digital_outputs(indices(1, 1));
        end

        % Button pushed function: DeleteAngleButton
        function DeleteAngleButtonPushed(app, event)
            if app.current_angle_selected == -1
                error('Nothing is selected in the table')
            end
            
            app.test_digital_outputs(app.current_angle_selected) = [];
            app.test_pan_slider_val(app.current_angle_selected) = [];
            app.test_tilt_slider_val(app.current_angle_selected) = [];
            app.UITable.Data = [app.test_pan_slider_val, app.test_tilt_slider_val, app.test_digital_outputs]; %update the table of tilt angles
            
            fhv = app.UIFigure; % Current app status
            app.UIFigure.HandleVisibility = 'callback'; % give access to callback            
            set(0, 'CurrentFigure', app.UIFigure)
            plot(app.test_pan_slider_val - 27, app.test_tilt_slider_val - 74, 'o', "MarkerFaceColor", "red");
            app.UIFigure.HandleVisibility = fhv.HandleVisibility; % return original state
        end

        % Value changed function: PanSlider
        function PanSliderValueChanged(app, event)
            pan_value = round(app.PanSlider.Value);
            app.PanSlider.Value = pan_value;
            tilt_value = app.TiltSlider.Value;
            disp(["The pan value is ", pan_value + 27]);
            disp(["The tilt value is ", tilt_value + 74]);
            
            app.digital_output = pan_value + bitshift(tilt_value, 7); % saves the pan and tilt values as digital outputs in the correct format.
            if app.LaserSwitch.Value == "On"
                xippmex('digout', 5, app.digital_output + app.laser_output);
            else
                xippmex('digout', 5, app.digital_output + app.laser_output);
            end
        end

        % Value changed function: TiltSlider
        function TiltSliderValueChanged(app, event)
            tilt_value = round(app.TiltSlider.Value);
            app.TiltSlider.Value = tilt_value;
            pan_value = app.PanSlider.Value;
            disp(["The pan value is ", pan_value + 27]);
            disp(["The tilt value is ", tilt_value + 74]);
            
            app.digital_output = pan_value + bitshift(tilt_value, 7); % saves the pan and tilt values as digital outputs in the correct format.
            if app.LaserSwitch.Value == "On"
                xippmex('digout', 5, app.digital_output + app.laser_output);
            else
                xippmex('digout', 5, app.digital_output + app.laser_output);
            end
            
        end

        % Value changed function: LaserSwitch
        function LaserSwitchValueChanged(app, event)
            value = app.LaserSwitch.Value;
            if value == "On"
                xippmex('digout', 5, app.digital_output + app.laser_output); %if the toggle button is on, turn on the laser with whatever the current servo postions are
            else
                xippmex('digout', 5, app.digital_output); %if the toggle button is off, turn off the laser with whatever the current servo postions are
            end
        end

        % Button down function: ServoAngleGrid
        function ServoAngleGridButtonDown(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure; % Current app status
            app.UIFigure.HandleVisibility = 'callback'; % give access to callback            
            set(0, 'CurrentFigure', app.UIFigure)
            [mouse_pan, mouse_tilt] = ginput(1); % get the selected angles
            pan_value = round(mouse_pan);
            tilt_value = round(mouse_tilt);
            disp(["The pan value is ", pan_value + 27]);
            disp(["The tilt value is ", tilt_value + 74]);
            app.UIFigure.HandleVisibility = fhv.HandleVisibility; % return original state
            
            app.PanSlider.Value = pan_value;
            app.TiltSlider.Value = tilt_value;
            
            app.digital_output = pan_value + bitshift(tilt_value, 7); % saves the pan and tilt values as digital outputs in the correct format.
            if app.LaserSwitch.Value == "On"
                xippmex('digout', 5, app.digital_output + app.laser_output);
            else
                xippmex('digout', 5, app.digital_output + app.laser_output);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LaserSweepButton = uibutton(app.UIFigure, 'push');
            app.LaserSweepButton.ButtonPushedFcn = createCallbackFcn(app, @LaserSweepButtonPushed, true);
            app.LaserSweepButton.Text = 'Laser Sweep';
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.RepeatsSpinnerLabel = uilabel(app.UIFigure);
            app.RepeatsSpinnerLabel.Text = 'Repeats';
            app.RepeatsSpinner = uispinner(app.UIFigure);
            app.RepeatsSpinner.Limits = [1 50];
            app.RepeatsSpinner.Value = 10;
            app.TestRunningLampLabel = uilabel(app.UIFigure);
            app.TestRunningLampLabel.Text = 'Test Running';
            app.TestRunningLamp = uilamp(app.UIFigure);
            app.StopTestButton = uibutton(app.UIFigure, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @StopTestButtonPushed, true);
            app.StopTestButton.Text = 'Stop Test';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Pan Angles'; 'Tilt Angles'; 'Digital Output'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveAngleButton = uibutton(app.UIFigure, 'push');
            app.SaveAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAngleButtonPushed, true);
            app.SaveAngleButton.Text = 'Save Angle';
            app.DeleteAngleButton = uibutton(app.UIFigure, 'push');
            app.DeleteAngleButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAngleButtonPushed, true);
            app.DeleteAngleButton.Text = 'Delete Angle';
            app.PanSliderLabel = uilabel(app.UIFigure);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.UIFigure);
            app.PanSlider.Limits = [0 127];
            app.PanSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.PanSlider.MajorTickLabels = {'27', '37', '47', '57', '67', '77', '87', '97', '107', '117', '127', '137', '147'};
            app.PanSlider.ValueChangedFcn = createCallbackFcn(app, @PanSliderValueChanged, true);
            app.PanSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 11 12 13 14 15 16 17 18 19 21 22 23 24 25 26 27 28 29 31 32 33 34 35 36 37 38 39 41 42 43 44 45 46 47 48 49 51 52 53 54 55 56 57 58 59 61 62 63 64 65 66 67 68 69 71 72 73 74 75 76 77 78 79 81 82 83 84 85 86 87 88 89 91 92 93 94 95 96 97 98 99 101 102 103 104 105 106 107 108 109 111 112 113 114 115 116 117 118 119 121 122 123 124 125 126 127];
            app.PanSlider.Value = 63;
            app.TiltSliderLabel = uilabel(app.UIFigure);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.UIFigure);
            app.TiltSlider.Limits = [0 31];
            app.TiltSlider.MajorTicks = [0 5 10 15 20 25 30 35];
            app.TiltSlider.MajorTickLabels = {'74', '79', '84', '89', '94', '99', '104'};
            app.TiltSlider.ValueChangedFcn = createCallbackFcn(app, @TiltSliderValueChanged, true);
            app.TiltSlider.Value = 16;
            app.TestNumberGaugeLabel = uilabel(app.UIFigure);
            app.TestNumberGaugeLabel.Text = 'Test Number';
            app.TestNumberGauge = uigauge(app.UIFigure, 'circular');
            app.TestNumberGauge.Limits = [1 100];
            app.LaserSwitchLabel = uilabel(app.UIFigure);
            app.LaserSwitchLabel.Text = 'Laser';
            app.LaserSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LaserSwitch.ValueChangedFcn = createCallbackFcn(app, @LaserSwitchValueChanged, true);
            app.ServoAngleGrid = uiaxes(app.UIFigure);
            xlabel(app.ServoAngleGrid, 'Pan')
            ylabel(app.ServoAngleGrid, 'Tilt')
            zlabel(app.ServoAngleGrid, 'Z')
            app.ServoAngleGrid.XLim = [0 127];
            app.ServoAngleGrid.YLim = [0 31];
            app.ServoAngleGrid.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.ServoAngleGrid.XTickLabel = {'27'; '37'; '47'; '57'; '67'; '77'; '87'; '97'; '107'; '117'; '127'; '137'; '147'};
            app.ServoAngleGrid.YTick = [0 5 10 15 20 25 30 35];
            app.ServoAngleGrid.YTickLabel = {'74'; '79'; '84'; '89'; '94'; '99'; '104'};
            app.ServoAngleGrid.ButtonDownFcn = createCallbackFcn(app, @ServoAngleGridButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kaoLightTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end